classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadFileMenu                  matlab.ui.container.Menu
        LoadWedgesFileMenu            matlab.ui.container.Menu
        LoadConstraintsFileMenu       matlab.ui.container.Menu
        LoadStrategyFileMenu          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        UIAxes                        matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        StrategiesTab                 matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        ElectricityProductionTab      matlab.ui.container.Tab
        ElectricityStrategySelectionListBoxLabel  matlab.ui.control.Label
        ElectricityStrategySelectionListBox  matlab.ui.control.ListBox
        HeatingandDirectFuelUseTab    matlab.ui.container.Tab
        FuelStrategySelectionListBoxLabel  matlab.ui.control.Label
        FuelStrategySelectionListBox  matlab.ui.control.ListBox
        TransportationTab             matlab.ui.container.Tab
        TransportationStrategySelectionListBoxLabel  matlab.ui.control.Label
        TransportationStrategySelectionListBox  matlab.ui.control.ListBox
        BiostorageTab                 matlab.ui.container.Tab
        BiostorageStrategySelectionListBoxLabel  matlab.ui.control.Label
        BiostorageStrategySelectionListBox  matlab.ui.control.ListBox
        TogglebetweenthetabsbelowtoselectstrategiesfromdifferentLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Tab2                          matlab.ui.container.Tab
    end


    properties (Access = private)
        WedgesData      % This property will hold processed data.
        ConstraintsData % This property will hold processed data.
        StrategyData    % This property will hold processed data.
    end

    methods (Access = private)
    
        function WedgesData = readWedges(app, fileID)
            % This fucntion's purpose is to read in and process
            % the wedges file and outputs the data as a matrix.
            % In the matrix M(1,1) would correspond to the label of the first wedge
            % M(2,2) would correspind to the value of the second wedge. Etc.
            
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
             
        end
        
        
        function ConstraintsData = readConstraints(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~
        end
        
        
        function StrategyData = readStrategy(app, fileID)
            %~~~~~~~~~~~~
            % STILL UNDER CONSTRUCTION
            %~~~~~~~~~~~~  
        end
    end

    methods (Access = private)

        % Callback function
        function LoadWedgesButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = uigetfile;
            
            try % Try to open the file
                
                fileID = fopen(fileName);
                % Check if fileName is a valid address.
                if fileID == -1
                    % Change the lamp color to red.
                    app.LampWedges.Color = [1 0 0];
                else 
%                     climatewedges.WedgesData = readWedges(fileID);
                    % Change the lamp color to green.
                    app.LampWedges.Color = [0 1 0];
                end
                
            catch % The file could not be opened
                
                app.LampWedges.Color = [1 0 0];
                disp('Please enter a valid file address.');
                
            end
        end

        % Callback function
        function LoadConstraintsButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.ConstraintsEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampConstraints.Color = [1 0 0];
                else  
%                     climatewedges.ConstraintsData = readConstraints(fileID);
                    app.LampConstraints.Color = [0 1 0];
                end
            catch
                app.LampConstraints.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end

        % Callback function
        function LoadStrategyButtonPushed(app, event)
            % This callback acts when a button is pressed,
            % all it does is open the the file using fopen(),
            % then it will provide feedback via the specified lamp.
            
            
            fileName = app.StrategyEditField.Value;
            try
                fileID = fopen(fileName);
                if fileID == -1
                    app.LampStrategy.Color = [1 0 0];
                else
%                     climatewedges.StrategyData = readStrategy(fileID);
                    app.LampStrategy.Color = [0 1 0];
                end
            catch
                app.LampStrategy.Color = [1 0 0];
                disp('Please enter a valid file address.');
            end
        end

        % Menu selected function: LoadWedgesFileMenu
        function LoadWedgesFileMenuSelected(app, event)
            fileName = uigetfile
            fileID = fopen(fileName);
            
            %climatewedge.StrategyData = readStrategy(fileID);
        end

        % Value changed function: ElectricityStrategySelectionListBox
        function ElectricityStrategySelectionListBoxValueChanged(app, event)
            
            value = app.ElectricityStrategySelectionListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.ElectricityStrategySelectionListBox.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: 
        % TransportationStrategySelectionListBox
        function TransportationStrategySelectionListBoxValueChanged(app, event)
            
            value = app.TransportationStrategySelectionListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.TransportationStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: FuelStrategySelectionListBox
        function FuelStrategySelectionListBoxValueChanged(app, event)
            
            value = app.FuelStrategySelectionListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.FuelStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end

        % Value changed function: BiostorageStrategySelectionListBox
        function BiostorageStrategySelectionListBoxValueChanged(app, event)
           
            value = app.BiostorageStrategySelectionListBox.Value;
            %the line below is to assign each strategy as an item in the list
            %app.BiostorageStrategySelection.Items=Strategies(:);
            %limit decided by constraints (can select 4 out of 6 for example)
            %if no limit is given in constraint file, then you can select all strategies
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadFileMenu = uimenu(app.UIFigure);
            app.LoadFileMenu.Text = 'Load File';
            app.LoadWedgesFileMenu = uimenu(app.LoadFileMenu);
            app.LoadWedgesFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadWedgesFileMenuSelected, true);
            app.LoadWedgesFileMenu.Text = 'Load Wedges File';
            app.LoadConstraintsFileMenu = uimenu(app.LoadFileMenu);
            app.LoadConstraintsFileMenu.Text = 'Load Constraints File';
            app.LoadStrategyFileMenu = uimenu(app.LoadFileMenu);
            app.LoadStrategyFileMenu.Text = 'Load Strategy File';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StrategiesTab = uitab(app.TabGroup);
            app.StrategiesTab.Title = 'Strategies';
            app.TabGroup2 = uitabgroup(app.StrategiesTab);
            app.ElectricityProductionTab = uitab(app.TabGroup2);
            app.ElectricityProductionTab.Title = 'Electricity Production';
            app.ElectricityStrategySelectionListBoxLabel = uilabel(app.ElectricityProductionTab);
            app.ElectricityStrategySelectionListBoxLabel.Text = 'Electricity Strategy Selection';
            app.ElectricityStrategySelectionListBox = uilistbox(app.ElectricityProductionTab);
            app.ElectricityStrategySelectionListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.ElectricityStrategySelectionListBox.Multiselect = 'on';
            app.ElectricityStrategySelectionListBox.ValueChangedFcn = createCallbackFcn(app, @ElectricityStrategySelectionListBoxValueChanged, true);
            app.ElectricityStrategySelectionListBox.Value = {'Strategy 1'};
            app.HeatingandDirectFuelUseTab = uitab(app.TabGroup2);
            app.HeatingandDirectFuelUseTab.Title = 'Heating and Direct Fuel Use';
            app.FuelStrategySelectionListBoxLabel = uilabel(app.HeatingandDirectFuelUseTab);
            app.FuelStrategySelectionListBoxLabel.Text = 'Fuel Strategy Selection';
            app.FuelStrategySelectionListBox = uilistbox(app.HeatingandDirectFuelUseTab);
            app.FuelStrategySelectionListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.FuelStrategySelectionListBox.Multiselect = 'on';
            app.FuelStrategySelectionListBox.ValueChangedFcn = createCallbackFcn(app, @FuelStrategySelectionListBoxValueChanged, true);
            app.FuelStrategySelectionListBox.Value = {'Strategy 1'};
            app.TransportationTab = uitab(app.TabGroup2);
            app.TransportationTab.Title = 'Transportation';
            app.TransportationStrategySelectionListBoxLabel = uilabel(app.TransportationTab);
            app.TransportationStrategySelectionListBoxLabel.Text = 'Transportation Strategy Selection';
            app.TransportationStrategySelectionListBox = uilistbox(app.TransportationTab);
            app.TransportationStrategySelectionListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.TransportationStrategySelectionListBox.Multiselect = 'on';
            app.TransportationStrategySelectionListBox.ValueChangedFcn = createCallbackFcn(app, @TransportationStrategySelectionListBoxValueChanged, true);
            app.TransportationStrategySelectionListBox.Value = {'Strategy 1'};
            app.BiostorageTab = uitab(app.TabGroup2);
            app.BiostorageTab.Title = 'Biostorage';
            app.BiostorageStrategySelectionListBoxLabel = uilabel(app.BiostorageTab);
            app.BiostorageStrategySelectionListBoxLabel.Text = 'Biostorage Strategy Selection';
            app.BiostorageStrategySelectionListBox = uilistbox(app.BiostorageTab);
            app.BiostorageStrategySelectionListBox.Items = {'Strategy 1', 'Strategy 2', 'Strategy 3', 'Strategy 4'};
            app.BiostorageStrategySelectionListBox.Multiselect = 'on';
            app.BiostorageStrategySelectionListBox.ValueChangedFcn = createCallbackFcn(app, @BiostorageStrategySelectionListBoxValueChanged, true);
            app.BiostorageStrategySelectionListBox.Value = {'Strategy 1'};
            app.TogglebetweenthetabsbelowtoselectstrategiesfromdifferentLabel = uilabel(app.StrategiesTab);
            app.TogglebetweenthetabsbelowtoselectstrategiesfromdifferentLabel.Text = 'Toggle between the tabs below to select strategies from different sectors.';
            app.Label = uilabel(app.StrategiesTab);
            app.Label.Text = 'Some sectors may have constraints on how many strategies can be selected at a time.';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end